classdef Source_Code_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        CloseButton       matlab.ui.control.Button
        ResetButton       matlab.ui.control.Button
        BCIButton         matlab.ui.control.Button
        LoadButton        matlab.ui.control.Button
        ROIButton         matlab.ui.control.Button
        ProcessingButton  matlab.ui.control.Button
        BrowseButton      matlab.ui.control.Button
        ImageInput_36     matlab.ui.control.UIAxes
        ImageInput_35     matlab.ui.control.UIAxes
        ImageInput_34     matlab.ui.control.UIAxes
        ImageInput_33     matlab.ui.control.UIAxes
        ImageInput_32     matlab.ui.control.UIAxes
        ImageInput_31     matlab.ui.control.UIAxes
        ImageInput_30     matlab.ui.control.UIAxes
        ImageInput_29     matlab.ui.control.UIAxes
        ImageInput_28     matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        ImageInput_27     matlab.ui.control.UIAxes
        ImageInput_26     matlab.ui.control.UIAxes
        ImageInput_25     matlab.ui.control.UIAxes
        ImageInput_24     matlab.ui.control.UIAxes
        ImageInput_23     matlab.ui.control.UIAxes
        ImageInput_22     matlab.ui.control.UIAxes
        ImageInput_21     matlab.ui.control.UIAxes
        ImageInput_20     matlab.ui.control.UIAxes
        ImageInput_19     matlab.ui.control.UIAxes
        ImageInput_18     matlab.ui.control.UIAxes
        ImageInput_17     matlab.ui.control.UIAxes
        ImageInput_16     matlab.ui.control.UIAxes
        ImageInput_15     matlab.ui.control.UIAxes
        ImageInput_14     matlab.ui.control.UIAxes
        ImageInput_13     matlab.ui.control.UIAxes
        ImageInput_12     matlab.ui.control.UIAxes
        ImageInput_11     matlab.ui.control.UIAxes
        ImageInput_10     matlab.ui.control.UIAxes
        ImageInput_9      matlab.ui.control.UIAxes
        ImageInput_8      matlab.ui.control.UIAxes
        ImageInput_7      matlab.ui.control.UIAxes
        ImageInput_6      matlab.ui.control.UIAxes
        ImageInput_5      matlab.ui.control.UIAxes
        ImageInput_4      matlab.ui.control.UIAxes
        ImageInput_3      matlab.ui.control.UIAxes
        ImageInput_2      matlab.ui.control.UIAxes
        ImageInput_1      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        InputImage1;
        InputImage2;
        InputImage3;
        InputImage4;
        InputImage5;
        InputImage6;
        InputImage7;
        InputImage8;
        InputImage9;
        Contrast1;
        Contrast2;
        Contrast3;
        Contrast4;
        Contrast5;
        Contrast6;
        Contrast7;
        Contrast8;
        Contrast9;
        CropIm1;
        CropIm2;
        CropIm3;
        CropIm4;
        CropIm5;
        CropIm6;
        CropIm7;
        CropIm8;
        CropIm9;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

prompt = {'Enater Image No.:'};
dlgtitle = 'Input Image';
dims = [1 35];
answer = inputdlg(prompt,dlgtitle,dims);
ImgId = answer{1};
name_ = "Pick Image "+ImgId

if app.ImageInput_1.Title.String == "Image "+ImgId
    
        [filename, pathname] = uigetfile('*.*',name_);
         if isequal(filename,0) || isequal(pathname,0)
                 helpdlg('User pressed cancel');
         else
              InputImage=imread([pathname, filename]);
              imshow(InputImage, 'Parent',app.ImageInput_1);
              app.InputImage1=InputImage;
         end

elseif app.ImageInput_2.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_2);
          app.InputImage2=InputImage;
     end
     
elseif app.ImageInput_3.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_3);
          app.InputImage3=InputImage;
     end

elseif app.ImageInput_4.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_4);
          app.InputImage4=InputImage;
     end

 elseif app.ImageInput_5.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_5);
          app.InputImage5=InputImage;
     end

 elseif app.ImageInput_6.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_6);
          app.InputImage6=InputImage;
     end

 elseif app.ImageInput_7.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_7);
          app.InputImage7=InputImage;
     end

 elseif app.ImageInput_8.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_8);
          app.InputImage8=InputImage;
     end

 elseif app.ImageInput_9.Title.String == "Image "+ImgId

        [filename, pathname] = uigetfile('*.*',name_);
     if isequal(filename,0) || isequal(pathname,0)
             helpdlg('User pressed cancel');
     else
          InputImage=imread([pathname, filename]);
          imshow(InputImage, 'Parent',app.ImageInput_9);
          app.InputImage9=InputImage;
     end
end
        end

        % Button pushed function: ProcessingButton
        function ProcessingButtonPushed(app, event)
            
        app.InputImage1 = app.InputImage1;
        [~, ~, numberOfColorChannels] = size(app.InputImage1);
        if numberOfColorChannels > 1
            % It's not really gray s
            % cale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage1 = app.InputImage1(:, :, 1);
        end
        Kmedian1 = medfilt2(app.InputImage1); %preprocessing
        app.Contrast1 = imadjust(Kmedian1);
        imshow(app.Contrast1,'Parent',app.ImageInput_10)
        app.Contrast1=app.Contrast1;

        app.InputImage2 = app.InputImage2;
        [~, ~, numberOfColorChannels] = size(app.InputImage2);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage2 = app.InputImage2(:, :, 1);
        end
        Kmedian2 = medfilt2(app.InputImage2); %preprocessing
        app.Contrast2 = imadjust(Kmedian2);
        imshow(app.Contrast2,'Parent',app.ImageInput_11)
        app.Contrast2=app.Contrast2;

        app.InputImage3 = app.InputImage3;
        [~, ~, numberOfColorChannels] = size(app.InputImage3);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage3 = app.InputImage3(:, :, 1);
        end
        Kmedian3 = medfilt2(app.InputImage3); %preprocessing
        app.Contrast3 = imadjust(Kmedian3);
        imshow(app.Contrast3,'Parent',app.ImageInput_12)
        app.Contrast3=app.Contrast3;

        app.InputImage4 = app.InputImage4;
        [~, ~, numberOfColorChannels] = size(app.InputImage4);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage4 = app.InputImage4(:, :, 1);
        end
        Kmedian4 = medfilt2(app.InputImage4); %preprocessing
        app.Contrast4 = imadjust(Kmedian4);
        imshow(app.Contrast4,'Parent',app.ImageInput_13)
        app.Contrast4=app.Contrast4;

        app.InputImage5 = app.InputImage5;
        [~, ~, numberOfColorChannels] = size(app.InputImage5);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage5 = app.InputImage5(:, :, 1);
        end
        Kmedian5 = medfilt2(app.InputImage5); %preprocessing
        app.Contrast5 = imadjust(Kmedian5);
        imshow(app.Contrast5,'Parent',app.ImageInput_14)
        app.Contrast5=app.Contrast5;

        app.InputImage6 = app.InputImage6;
        [~, ~, numberOfColorChannels] = size(app.InputImage6);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage6 = app.InputImage6(:, :, 1);
        end
        Kmedian6 = medfilt2(app.InputImage6); %preprocessing
        app.Contrast6 = imadjust(Kmedian6);
        imshow(app.Contrast6,'Parent',app.ImageInput_15)
        app.Contrast6=app.Contrast6;

        app.InputImage7 = app.InputImage7;
        [~, ~, numberOfColorChannels] = size(app.InputImage7);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage7 = app.InputImage7(:, :, 1);
        end
        Kmedian7 = medfilt2(app.InputImage7); %preprocessing
        app.Contrast7 = imadjust(Kmedian7);
        imshow(app.Contrast7,'Parent',app.ImageInput_16)
        app.Contrast7=app.Contrast7;

        app.InputImage8 = app.InputImage8;
        [~, ~, numberOfColorChannels] = size(app.InputImage8);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage8 = app.InputImage8(:, :, 1);
        end
        Kmedian8 = medfilt2(app.InputImage8); %preprocessing
        app.Contrast8 = imadjust(Kmedian8);
        imshow(app.Contrast8,'Parent',app.ImageInput_17)
        app.Contrast8=app.Contrast8;

        app.InputImage9 = app.InputImage9;
        [~, ~, numberOfColorChannels] = size(app.InputImage9);
        if numberOfColorChannels > 1
            % It's not really gray scale like we expected - it's color.
            % Extract the red channel (so the magenta lines will be white).
            app.InputImage9 = app.InputImage9(:, :, 1);
        end
        Kmedian9 = medfilt2(app.InputImage9); %preprocessing
        app.Contrast9 = imadjust(Kmedian9);
        imshow(app.Contrast9,'Parent',app.ImageInput_18)
        app.Contrast9=app.Contrast9;

        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            app.Contrast1 = app.Contrast1;
        app.CropIm1 = imcrop(app.Contrast1,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm1,'Parent',app.ImageInput_19)
        app.CropIm1=app.CropIm1;

        app.Contrast2 = app.Contrast2;
        app.CropIm2 = imcrop(app.Contrast2,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm2,'Parent',app.ImageInput_20)
        app.CropIm2=app.CropIm2;

        app.Contrast3 = app.Contrast3;
        app.CropIm3 = imcrop(app.Contrast3,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm3,'Parent',app.ImageInput_21)
        app.CropIm3=app.CropIm3;

        app.Contrast4 = app.Contrast4;
        app.CropIm4 = imcrop(app.Contrast4,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm4,'Parent',app.ImageInput_22)
        app.CropIm4=app.CropIm4;

        app.Contrast5 = app.Contrast5;
        app.CropIm5 = imcrop(app.Contrast5,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm5,'Parent',app.ImageInput_23)
        app.CropIm5=app.CropIm5;

        app.Contrast6 = app.Contrast6;
        app.CropIm6 = imcrop(app.Contrast6,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm6,'Parent',app.ImageInput_24)
        app.CropIm6=app.CropIm6;

        app.Contrast7 = app.Contrast7;
        app.CropIm7 = imcrop(app.Contrast7,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm7,'Parent',app.ImageInput_25)
        app.CropIm7=app.CropIm7;

        app.Contrast8 = app.Contrast8;
        app.CropIm8 = imcrop(app.Contrast8,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm8,'Parent',app.ImageInput_26)
        app.CropIm8=app.CropIm8;

        app.Contrast9 = app.Contrast9;
        app.CropIm9 = imcrop(app.Contrast9,[200 210 400 160]); %[xmin ymin xlenght ylenght] | x=col y=row
        imshow(app.CropIm9,'Parent',app.ImageInput_27)
        app.CropIm9=app.CropIm9;
            
        end

        % Button pushed function: BCIButton
        function BCIButtonPushed(app, event)
            app.CropIm1=app.CropIm1;
            verticalProfile = mean(app.CropIm1, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm1);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B1 = uint8(max(backgroundImage(:)) * double(app.CropIm1) ./ backgroundImage);
            imshow(B1,'Parent',app.ImageInput_28);

            app.CropIm2=app.CropIm2;
            verticalProfile = mean(app.CropIm2, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm2);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B2 = uint8(max(backgroundImage(:)) * double(app.CropIm2) ./ backgroundImage);
            imshow(B2,'Parent',app.ImageInput_29);

            app.CropIm3=app.CropIm3;
            verticalProfile = mean(app.CropIm3, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm3);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B3 = uint8(max(backgroundImage(:)) * double(app.CropIm3) ./ backgroundImage);
            imshow(B3,'Parent',app.ImageInput_30);

            app.CropIm4=app.CropIm4;
            verticalProfile = mean(app.CropIm4, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm4);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B4 = uint8(max(backgroundImage(:)) * double(app.CropIm4) ./ backgroundImage);
            imshow(B4,'Parent',app.ImageInput_31);

            app.CropIm5=app.CropIm5;
            verticalProfile = mean(app.CropIm5, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm5);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B5 = uint8(max(backgroundImage(:)) * double(app.CropIm5) ./ backgroundImage);
            imshow(B5,'Parent',app.ImageInput_32);

            app.CropIm6=app.CropIm6;
            verticalProfile = mean(app.CropIm6, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm6);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B6 = uint8(max(backgroundImage(:)) * double(app.CropIm6) ./ backgroundImage);
            imshow(B6,'Parent',app.ImageInput_33);

            app.CropIm7=app.CropIm7;
            verticalProfile = mean(app.CropIm7, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm7);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B7 = uint8(max(backgroundImage(:)) * double(app.CropIm7) ./ backgroundImage);
            imshow(B7,'Parent',app.ImageInput_34);

            app.CropIm8=app.CropIm8;
            verticalProfile = mean(app.CropIm8, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm8);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B8 = uint8(max(backgroundImage(:)) * double(app.CropIm8) ./ backgroundImage);
            imshow(B8,'Parent',app.ImageInput_35);

            app.CropIm9=app.CropIm9;
            verticalProfile = mean(app.CropIm9, 2); % Mean of Matrix Row 
            verticalProfile = movmax(verticalProfile, 13); %moving maximum
            xFit = 1 : length(verticalProfile);
            coefficients = polyfit(xFit, verticalProfile, 6); %coefficients
            [~, columns] = size(app.CropIm9);
            yFit = polyval(coefficients, xFit); %polyval
            backgroundImage = repmat(yFit(:), [1, columns]);
            B9 = uint8(max(backgroundImage(:)) * double(app.CropIm9) ./ backgroundImage);
            imshow(B9,'Parent',app.ImageInput_36);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            C = xlsread('correlation.xlsx');
            plot(app.UIAxes3,C);
        end

        % Callback function: ImageInput_1, ImageInput_28, ResetButton
        function ResetButtonPushed(app, event)
            cla(app.ImageInput_1);
            cla(app.ImageInput_2);
            cla(app.ImageInput_3);
            cla(app.ImageInput_4);
            cla(app.ImageInput_5);
            cla(app.ImageInput_6);
            cla(app.ImageInput_7);
            cla(app.ImageInput_8);
            cla(app.ImageInput_9);
            cla(app.ImageInput_10);
            cla(app.ImageInput_11);
            cla(app.ImageInput_12);
            cla(app.ImageInput_13);
            cla(app.ImageInput_14);
            cla(app.ImageInput_15);
            cla(app.ImageInput_16);
            cla(app.ImageInput_17);
            cla(app.ImageInput_18);
            cla(app.ImageInput_19);
            cla(app.ImageInput_20);
            cla(app.ImageInput_21);
            cla(app.ImageInput_22);
            cla(app.ImageInput_23);
            cla(app.ImageInput_24);
            cla(app.ImageInput_25);
            cla(app.ImageInput_26);
            cla(app.ImageInput_27);
            cla(app.ImageInput_28);
            cla(app.ImageInput_29);
            cla(app.ImageInput_30);
            cla(app.ImageInput_31);
            cla(app.ImageInput_32);
            cla(app.ImageInput_33);
            cla(app.ImageInput_34);
            cla(app.ImageInput_35);
            cla(app.ImageInput_36);
            cla(app.UIAxes3);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageInput_1 = uiaxes(app.UIFigure);
            title(app.ImageInput_1, 'Image 1')
            app.ImageInput_1.XTick = [];
            app.ImageInput_1.YTick = [];
            app.ImageInput_1.ButtonDownFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ImageInput_2 = uiaxes(app.UIFigure);
            title(app.ImageInput_2, 'Image 2')
            app.ImageInput_2.XTick = [];
            app.ImageInput_2.YTick = [];
            app.ImageInput_3 = uiaxes(app.UIFigure);
            title(app.ImageInput_3, 'Image 3')
            app.ImageInput_3.XTick = [];
            app.ImageInput_3.YTick = [];
            app.ImageInput_4 = uiaxes(app.UIFigure);
            title(app.ImageInput_4, 'Image 4')
            app.ImageInput_4.XTick = [];
            app.ImageInput_4.YTick = [];
            app.ImageInput_5 = uiaxes(app.UIFigure);
            title(app.ImageInput_5, 'Image 5')
            app.ImageInput_5.XTick = [];
            app.ImageInput_5.YTick = [];
            app.ImageInput_6 = uiaxes(app.UIFigure);
            title(app.ImageInput_6, 'Image 6')
            app.ImageInput_6.XTick = [];
            app.ImageInput_6.YTick = [];
            app.ImageInput_7 = uiaxes(app.UIFigure);
            title(app.ImageInput_7, 'Image 7')
            app.ImageInput_7.XTick = [];
            app.ImageInput_7.YTick = [];
            app.ImageInput_8 = uiaxes(app.UIFigure);
            title(app.ImageInput_8, 'Image 8')
            app.ImageInput_8.XTick = [];
            app.ImageInput_8.YTick = [];
            app.ImageInput_9 = uiaxes(app.UIFigure);
            title(app.ImageInput_9, 'Image 9')
            app.ImageInput_9.XTick = [];
            app.ImageInput_9.YTick = [];
            app.ImageInput_10 = uiaxes(app.UIFigure);
            title(app.ImageInput_10, 'Image 1')
            app.ImageInput_10.XTick = [];
            app.ImageInput_10.YTick = [];
            app.ImageInput_11 = uiaxes(app.UIFigure);
            title(app.ImageInput_11, 'Image 2')
            app.ImageInput_11.XTick = [];
            app.ImageInput_11.YTick = [];
            app.ImageInput_12 = uiaxes(app.UIFigure);
            title(app.ImageInput_12, 'Image 3')
            app.ImageInput_12.XTick = [];
            app.ImageInput_12.YTick = [];
            app.ImageInput_13 = uiaxes(app.UIFigure);
            title(app.ImageInput_13, 'Image 4')
            app.ImageInput_13.XTick = [];
            app.ImageInput_13.YTick = [];
            app.ImageInput_14 = uiaxes(app.UIFigure);
            title(app.ImageInput_14, 'Image 5')
            app.ImageInput_14.XTick = [];
            app.ImageInput_14.YTick = [];
            app.ImageInput_15 = uiaxes(app.UIFigure);
            title(app.ImageInput_15, 'Image 6')
            app.ImageInput_15.XTick = [];
            app.ImageInput_15.YTick = [];
            app.ImageInput_16 = uiaxes(app.UIFigure);
            title(app.ImageInput_16, 'Image 7')
            app.ImageInput_16.XTick = [];
            app.ImageInput_16.YTick = [];
            app.ImageInput_17 = uiaxes(app.UIFigure);
            title(app.ImageInput_17, 'Image 8')
            app.ImageInput_17.XTick = [];
            app.ImageInput_17.YTick = [];
            app.ImageInput_18 = uiaxes(app.UIFigure);
            title(app.ImageInput_18, 'Image 9')
            app.ImageInput_18.XTick = [];
            app.ImageInput_18.YTick = [];
            app.ImageInput_19 = uiaxes(app.UIFigure);
            title(app.ImageInput_19, 'Image 1')
            app.ImageInput_19.XTick = [];
            app.ImageInput_19.YTick = [];
            app.ImageInput_20 = uiaxes(app.UIFigure);
            title(app.ImageInput_20, 'Image 2')
            app.ImageInput_20.XTick = [];
            app.ImageInput_20.YTick = [];
            app.ImageInput_21 = uiaxes(app.UIFigure);
            title(app.ImageInput_21, 'Image 3')
            app.ImageInput_21.XTick = [];
            app.ImageInput_21.YTick = [];
            app.ImageInput_22 = uiaxes(app.UIFigure);
            title(app.ImageInput_22, 'Image 4')
            app.ImageInput_22.XTick = [];
            app.ImageInput_22.YTick = [];
            app.ImageInput_23 = uiaxes(app.UIFigure);
            title(app.ImageInput_23, 'Image 5')
            app.ImageInput_23.XTick = [];
            app.ImageInput_23.YTick = [];
            app.ImageInput_24 = uiaxes(app.UIFigure);
            title(app.ImageInput_24, 'Image 6')
            app.ImageInput_24.XTick = [];
            app.ImageInput_24.YTick = [];
            app.ImageInput_25 = uiaxes(app.UIFigure);
            title(app.ImageInput_25, 'Image 7')
            app.ImageInput_25.XTick = [];
            app.ImageInput_25.YTick = [];
            app.ImageInput_26 = uiaxes(app.UIFigure);
            title(app.ImageInput_26, 'Image 8')
            app.ImageInput_26.XTick = [];
            app.ImageInput_26.YTick = [];
            app.ImageInput_27 = uiaxes(app.UIFigure);
            title(app.ImageInput_27, 'Image 9')
            app.ImageInput_27.XTick = [];
            app.ImageInput_27.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Images Vs Correlations')
            xlabel(app.UIAxes3, 'Images')
            ylabel(app.UIAxes3, 'Correlation Values')
            app.ImageInput_28 = uiaxes(app.UIFigure);
            title(app.ImageInput_28, 'Image 1')
            app.ImageInput_28.XTick = [];
            app.ImageInput_28.YTick = [];
            app.ImageInput_28.ButtonDownFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ImageInput_29 = uiaxes(app.UIFigure);
            title(app.ImageInput_29, 'Image 2')
            app.ImageInput_29.XTick = [];
            app.ImageInput_29.YTick = [];
            app.ImageInput_30 = uiaxes(app.UIFigure);
            title(app.ImageInput_30, 'Image 3')
            app.ImageInput_30.XTick = [];
            app.ImageInput_30.YTick = [];
            app.ImageInput_31 = uiaxes(app.UIFigure);
            title(app.ImageInput_31, 'Image 4')
            app.ImageInput_31.XTick = [];
            app.ImageInput_31.YTick = [];
            app.ImageInput_32 = uiaxes(app.UIFigure);
            title(app.ImageInput_32, 'Image 5')
            app.ImageInput_32.XTick = [];
            app.ImageInput_32.YTick = [];
            app.ImageInput_33 = uiaxes(app.UIFigure);
            title(app.ImageInput_33, 'Image 6')
            app.ImageInput_33.XTick = [];
            app.ImageInput_33.YTick = [];
            app.ImageInput_34 = uiaxes(app.UIFigure);
            title(app.ImageInput_34, 'Image 7')
            app.ImageInput_34.XTick = [];
            app.ImageInput_34.YTick = [];
            app.ImageInput_35 = uiaxes(app.UIFigure);
            title(app.ImageInput_35, 'Image 8')
            app.ImageInput_35.XTick = [];
            app.ImageInput_35.YTick = [];
            app.ImageInput_36 = uiaxes(app.UIFigure);
            title(app.ImageInput_36, 'Image 9')
            app.ImageInput_36.XTick = [];
            app.ImageInput_36.YTick = [];
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ProcessingButton = uibutton(app.UIFigure, 'push');
            app.ProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessingButtonPushed, true);
            app.ProcessingButton.Text = 'Processing';
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.BCIButton = uibutton(app.UIFigure, 'push');
            app.BCIButton.ButtonPushedFcn = createCallbackFcn(app, @BCIButtonPushed, true);
            app.BCIButton.Text = 'BCI';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Source_Code_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end